classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ChooseNetworkButton       matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        PredictionEditField       matlab.ui.control.EditField
        PredictionEditFieldLabel  matlab.ui.control.Label
        ClassifyButton            matlab.ui.control.Button
        ImportImageButton         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Load the pre-trained network and initialize properties
            load('Network.mat');
            %app.Network = Network;
            signds = imageDatastore("D:\college\FYP\Experimenting\test\stopping","IncludeSubfolders",true,"LabelSource","foldernames");
            signNames = signds.Labels;
            [signTrain, signTest] = splitEachLabel(signds, 0.8, 'randomized');
            resizeTestImgs = augmentedImageDatastore([224 224],signTest);
        end

        % Callback function: not associated with a component
        function NetworkButtonGroupSelectionChanged(app, event)
            %selectedButton = app.NetworkButtonGroup.SelectedObject;
            %load Network.mat
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            resizedImage = imresize(image, [224, 224]);
            predictedLabel = classify(app.Network, resizedImage);
            app.PredictionEditField.Value = char(predictedLabel);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
           [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'},'Select an image');
            if isequal(file, 0) || isequal(path, 0)
                return; % User cancelled the selection
            end
            selectedImage = imread(fullfile(path, file));
            
            app.updateImage(selectedImage);
            app.classifyImage(selectedImage); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.PredictionEditFieldLabel = uilabel(app.UIFigure);
            app.PredictionEditFieldLabel.Text = 'Prediction';
            app.PredictionEditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.ChooseNetworkButton = uibutton(app.UIFigure, 'push');
            app.ChooseNetworkButton.Text = 'Choose Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end