classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PredictButton          matlab.ui.control.Button
        ImportImageButton      matlab.ui.control.Button
        SelectButton           matlab.ui.control.Button
        NetworkEditField       matlab.ui.control.EditField
        NetworkEditFieldLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % [filename,path] = uigetfile();
            % %app.FileNameEditField.Value = filename;
            % app.NetworkEditField.Value = filename;
            % 
            % figure(app.UIFigure);
            [file, path] = uigetfile({'*.mat', 'MAT Files (*.mat)'; }, 'Select a file');
            if isequal(file, 0) || isequal(path, 0)
                % User cancelled the selection
                return;
        
            end
            app.NetworkEditField.Value = file;
            
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'},'Select an image');
            if isequal(file, 0) || isequal(path, 0)
                return; % User cancelled the selection
            end
           
            selectedImage = imread(fullfile(path, file));

            % display image
            imshow(selectedImage,'Parent',app.UIAxes);

            % changes title w.r.t file_name
            app.UIAxes.Title.String = file;

        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % Prediction
            signPrediction = classify(Network,resizeTestImgs);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NetworkEditFieldLabel = uilabel(app.UIFigure);
            app.NetworkEditFieldLabel.Text = 'Network';
            app.NetworkEditField = uieditfield(app.UIFigure, 'text');
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end