classdef gui_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        NetworkEditField       matlab.ui.control.EditField
        NetworkEditFieldLabel  matlab.ui.control.Label
        PredictButton          matlab.ui.control.Button
        ImportImageButton      matlab.ui.control.Button
        LoadNetworkButton      matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Network            % To store the loaded network data (matfile)
        LoadedNetworkName  % To store the name of the loaded network (string)
        SelectedImage      % To store the imported image (uint8 array)
        SelectedImagePath   % To store the full path of the selected image (string)
        PredictedLabel     % To store the predicted label from the network (string)
        ActualLabel        % To store the actual label of the image (string)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Initialize properties
            app.Network = [];
            app.LoadedNetworkName = "No network loaded.";
            app.SelectedImage = [];
            app.SelectedImagePath = ""; % Initialize SelectedImagePath
            app.PredictedLabel = "";
            app.ActualLabel = "";
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Select Network.mat file');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled or closed the file selection dialog
                return;
            end

            % Load the network
            app.Network = matfile(fullfile(filepath, filename));

            % Get the network name and display in the text field
            app.LoadedNetworkName = filename;
            app.NetworkEditField.Value = app.LoadedNetworkName;
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg;*.jpeg;*.png', 'Image files (*.jpg, *.jpeg, *.png)'; '*.*', 'All files'}, 'Select an image');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled or closed the file selection dialog
                return;
            end

            % Read and display the selected image
            app.SelectedImage = imread(fullfile(filepath, filename));
            imshow(app.SelectedImage, 'Parent', app.UIAxes);

            app.SelectedImagePath = fullfile(filepath, filename); % Store the full path
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if isempty(app.Network) || isempty(app.SelectedImage)
                % Check if both network and image are loaded
                msgbox('Please load the network and import an image before predicting.', 'Error', 'error');
                return;
            end

            % Resize and preprocess the image (assumed to be the same size required by the network)
            resizedImage = imresize(app.SelectedImage, [224, 224]);

            % Load the network from the matfile
            net = app.Network.Network;

            % Perform prediction using the loaded network
            app.PredictedLabel = char(classify(net, resizedImage));

            % Get the actual label from the folder name of the selected image
            [~, actualLabel, ~] = fileparts(fileparts(app.SelectedImagePath));

            % Update the actual label
            app.ActualLabel = actualLabel;
            

            % Display the predicted and actual labels
            msgbox(sprintf('Predicted Label: %s\nActual Label: %s', app.PredictedLabel, app.ActualLabel), 'Prediction Results');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Traffic Sign')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadNetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.NetworkEditFieldLabel = uilabel(app.UIFigure);
            app.NetworkEditFieldLabel.Text = 'Network';
            app.NetworkEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end