classdef gui_test_2Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DefectDetectorUIFigure         matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        ImportImagePanel               matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        BrowseButton                   matlab.ui.control.Button
        ChoosetheModelPanel            matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SelectedNetworkEditField       matlab.ui.control.EditField
        SelectedNetworkEditFieldLabel  matlab.ui.control.Label
        LoadNetworkButton              matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        PredictButton                  matlab.ui.control.Button
        TrafficSignPanel               matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        TrafficSignDefectDetectionLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Network            % To store the loaded network data (matfile)
        LoadedNetworkName  % To store the name of the loaded network (string)
        SelectedImage      % To store the imported image (uint8 array)
        SelectedImagePath   % To store the full path of the selected image (string)
        PredictedLabel     % To store the predicted label from the network (string)
        ActualLabel        % To store the actual label of the image (string)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
             % Initialize properties
            app.Network = [];
            app.LoadedNetworkName = "No network loaded.";
            app.SelectedImage = [];
            app.SelectedImagePath = ""; % Initialize SelectedImagePath
            app.PredictedLabel = "";
            app.ActualLabel = "";
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Select Network.mat file');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled or closed the file selection dialog
                return;
            end

            % Load the network
            app.Network = matfile(fullfile(filepath, filename));

            % Get the network name and display in the text field
            app.LoadedNetworkName = filename;
            app.SelectedNetworkEditField.Value = app.LoadedNetworkName;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg;*.jpeg;*.png', 'Image files (*.jpg, *.jpeg, *.png)'; '*.*', 'All files'}, 'Select an image');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled or closed the file selection dialog
                return;
            end

            % Read and display the selected image
            app.SelectedImage = imread(fullfile(filepath, filename));
            imshow(app.SelectedImage, 'Parent', app.UIAxes);

            app.SelectedImagePath = fullfile(filepath, filename); % Store the full path
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if isempty(app.Network) || isempty(app.SelectedImage)
                % Check if both network and image are loaded
                msgbox('Please load the network and import an image before predicting.', 'Error', 'error');
                return;
            end

            % Resize and preprocess the image (assumed to be the same size required by the network)
            resizedImage = imresize(app.SelectedImage, [224, 224]);

            % Load the network from the matfile
            net = app.Network.Network;

            % Perform prediction using the loaded network
            app.PredictedLabel = char(classify(net, resizedImage));

            % Get the actual label from the folder name of the selected image
            [~, actualLabel, ~] = fileparts(fileparts(app.SelectedImagePath));

            % Update the actual label
            app.ActualLabel = actualLabel;
            

            % Display the predicted and actual labels
            msgbox(sprintf('Predicted Label: %s\nActual Label: %s', app.PredictedLabel, app.ActualLabel), 'Prediction Results');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DefectDetectorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DefectDetectorUIFigure = uifigure('Visible', 'off');
            app.DefectDetectorUIFigure.Name = 'Defect Detector';
            app.DefectDetectorUIFigure.Icon = fullfile(pathToMLAPP, 'gui_v3_icon_1.png');
            app.DefectDetectorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DefectDetectorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.LeftPanel);
            app.ChoosetheModelPanel = uipanel(app.GridLayout6);
            app.ChoosetheModelPanel.Title = 'Choose the Model';
            app.GridLayout3 = uigridlayout(app.ChoosetheModelPanel);
            app.LoadNetworkButton = uibutton(app.GridLayout3, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.SelectedNetworkEditFieldLabel = uilabel(app.GridLayout3);
            app.SelectedNetworkEditFieldLabel.Text = 'Selected Network';
            app.SelectedNetworkEditField = uieditfield(app.GridLayout3, 'text');
            app.SelectedNetworkEditField.Editable = 'off';
            app.ImportImagePanel = uipanel(app.GridLayout6);
            app.ImportImagePanel.Title = 'Import Image';
            app.GridLayout5 = uigridlayout(app.ImportImagePanel);
            app.BrowseButton = uibutton(app.GridLayout5, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout2.Tooltip = {''};
            app.TrafficSignDefectDetectionLabel = uilabel(app.GridLayout2);
            app.TrafficSignDefectDetectionLabel.Text = 'Traffic Sign Defect Detection';
            app.TrafficSignPanel = uipanel(app.GridLayout2);
            app.TrafficSignPanel.Title = 'Traffic Sign';
            app.GridLayout4 = uigridlayout(app.TrafficSignPanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PredictButton = uibutton(app.GridLayout2, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.DefectDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test_2Panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DefectDetectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DefectDetectorUIFigure)
        end
    end
end