classdef gui_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DefectDetectorUIFigure     matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ClassifyButton             matlab.ui.control.Button
        DetectButton               matlab.ui.control.Button
        LocationButton             matlab.ui.control.Button
        Button                     matlab.ui.control.StateButton
        ImportImagePanel           matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        BrowseButton               matlab.ui.control.Button
        TrafficSignPanel           matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        UIAxes                     matlab.ui.control.UIAxes
        ChooseNeuralNetworksPanel  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        LoadDetectorButton         matlab.ui.control.Button
        LoadClassifierButton       matlab.ui.control.Button
        TrafficSignDefectDetectionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Network            % To store the loaded network data (matfile)
        Detector           % To store the loaded detector (matfile)
        LoadedNetworkName  % To store the name of the loaded network (string)
        SelectedImage      % To store the imported image (uint8 array)
        SelectedImagePath   % To store the full path of the selected image (string)
        PredictedLabel     % To store the predicted label from the network (string)
        ActualLabel        % To store the actual label of the image (string)
    end
    
    properties (Access = public)
        CurrentTheme = 'original'; % Track the current theme
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize properties
            movegui(app.DefectDetectorUIFigure, 'center');
            app.Network = [];
            app.Detector = [];
            app.LoadedNetworkName = "No network loaded.";
            app.SelectedImage = [];
            app.SelectedImagePath = ""; % Initialize SelectedImagePath
            app.PredictedLabel = "";
            app.ActualLabel = "";
            
        end

        % Button pushed function: LoadClassifierButton
        function LoadClassifierButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Select Network.mat file');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled or closed the file selection dialog
                return;
            end
            % Create and show the progress bar
            progressBar = waitbar(0.1, 'Loading Neural Network...', 'Name', 'Neural Network');
        
            try
                
                % Update progress bar
                waitbar(0.5, progressBar, 'Loading Neural Network...');  % Update to appropriate value
                
                
                % Load the network
                app.Network = matfile(fullfile(filepath, filename));
        
                % Update progress bar
                waitbar(1, progressBar, 'Loaded Neural Network...');  % Update to appropriate value
        

                app.LoadClassifierButton.Text = "Loaded Classifier";
        
                % Close the progress bar
                close(progressBar);
            catch
                % Close the progress bar in case of an error
                close(progressBar);
                errordlg('Error loading network.', 'Error', 'modal');
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            % Display file selection dialog
            [filename, filepath] = uigetfile({'*.jpg;*.jpeg;*.png', 'Image files (*.jpg, *.jpeg, *.png)'}, 'Select an image');
            
            % Check if the user canceled the dialog
            if isequal(filename, 0) || isequal(filepath, 0)
                return;
            end
            
            % Create a waitbar for image import
            ImportingProgressBar = waitbar(0, 'Importing...');
            
            try
                % Update progress bar message
                waitbar(0.3, ImportingProgressBar, 'Importing...');
                
                % Read and display the selected image
                app.SelectedImage = imread(fullfile(filepath, filename));
                imshow(app.SelectedImage, 'Parent', app.UIAxes);
                
                % Update progress bar message
                waitbar(0.7, ImportingProgressBar, 'Processing...');
                
                % Store the selected image path
                app.SelectedImagePath = fullfile(filepath, filename);
                
                % Close the progress bar
                waitbar(1, ImportingProgressBar, 'Done');
                pause(0.5); % Add a slight pause for the user to see the progress completion
                delete(ImportingProgressBar);
                
            catch exception
                % Handle exceptions and display an error message
                errordlg(['An error occurred during image import: ', exception.message], 'Image Import Error', 'modal');
                % Close the progress bar in case of an error
                delete(ImportingProgressBar);
            end
            app.SelectedImagePath = fullfile(filepath, filename); % Store the full path
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            if isempty(app.Network) || isempty(app.SelectedImage) %|| isempty(app.Detector)
                % Check if both network and image are loaded
                msgbox('Please load the networks and import an image before predicting.', 'Error', 'error');
                return;
            end
            % Create a progress bar
            predictProgressBar = waitbar(0.2, 'Predicting...');
            % Load the network from the matfile
            net = app.Network.Network;
            inputLayer = net.Layers(1);
            insz = inputLayer.InputSize(1:2);
        
            % Resize and preprocess the image (assumed to be the same size required by the network)
            resizedImage = imresize(app.SelectedImage, insz);
        
            % updating the progress bar
            waitbar(0.4,predictProgressBar,'Predicting...');
        
            try
                waitbar(0.7,predictProgressBar,'Predicting...');
                % Perform prediction using the loaded network
                app.PredictedLabel = char(classify(net, resizedImage));
                waitbar(1,predictProgressBar,'Showing Results');
                % Get the actual label from the folder name of the selected image
                [~, actualLabel, ~] = fileparts(fileparts(app.SelectedImagePath));
        
                % Update the actual label
                app.ActualLabel = actualLabel;
        
                % Display the predicted and actual labels
                msgbox(sprintf('Predicted Label: %s\nActual Label: %s', app.PredictedLabel, app.ActualLabel), 'Prediction Results');
        
            catch exception
                % Close the progress bar
                delete(predictProgressBar);

                % Handle any exceptions and display an error message
                errordlg(['An error occurred during prediction: ', exception.message], 'Prediction Error', 'modal');
            end
        
            % Close the progress bar
            delete(predictProgressBar);

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if strcmp(app.CurrentTheme, 'original')
                % Switch to dark theme
                setDarkThemev4(app);
                app.CurrentTheme = 'dark';

            else
                % Switch to original theme
                setOriginalThemev4(app);
                app.CurrentTheme = 'original';
            end
        end

        % Button pushed function: LoadDetectorButton
        function LoadDetectorButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Select the Object Detector file');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled or closed the file selection dialog
                return;
            end
            % Create and show the progress bar
            progressBar = waitbar(0.1, 'Loading Object Detector...', 'Name', 'Object Detector');
        
            try
                
                % Update progress bar
                waitbar(0.5, progressBar, 'Loading Object Detector...');  % Update to appropriate value
                
                
                % Load the network
                app.Detector = matfile(fullfile(filepath, filename));
        
                % Update progress bar
                waitbar(1, progressBar, 'Loaded Object Detector...');  % Update to appropriate value
                app.LoadDetectorButton.Text = "Loaded Detector";
        
                % Close the progress bar
                close(progressBar);
            catch
                % Close the progress bar in case of an error
                close(progressBar);
                errordlg('Error loading network.', 'Error', 'modal');
            end
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            if isempty(app.Detector) || isempty(app.SelectedImage)
                % Check if the detector and image are loaded
                msgbox('Please load the object detector and import an image before detecting.', 'Error', 'error');
                return;
            end
        
            % Create a progress bar
            detectProgressBar = waitbar(0.2, 'Detecting Objects...');
        
            try
                % Load the detector from the app data
                detector = app.Detector.detector;
        
                % Detect objects in the selected image
                [bboxes] = detect(detector, app.SelectedImage, 'Threshold', 0.01);
        
                % Check if any objects were detected
                if isempty(bboxes)
                    msgbox('No objects were detected in the image.', 'Object Detection', 'warn');
                    delete(detectProgressBar);
                    return;
                end
        
                % Draw bounding boxes on the original image
                detectedImage = insertObjectAnnotation(app.SelectedImage, 'rectangle', bboxes, 'Detected Object','LineWidth',15,'Color','cyan','FontSize',18);
        
                % updating the progress bar
                waitbar(0.7, detectProgressBar, 'Showing Results');
        
                % Display the original image with bounding boxes
                % imshow(detectedImage);
                imshow(detectedImage, 'Parent', app.UIAxes);
                title('Detected Objects');
        
                % Close the progress bar
                delete(detectProgressBar);
        
            catch exception
                % Close the progress bar
                delete(detectProgressBar);
        
                % Handle any exceptions and display an error message
                errordlg(['An error occurred during object detection: ', exception.message], 'Detection Error', 'modal');
            end
        end

        % Button pushed function: LocationButton
        function LocationButtonPushed(app, event)
            % Check if an image is loaded
            if isempty(app.SelectedImage)
                msgbox('Please load an image before extracting location information.', 'Information', 'warn');
                return;
            end
            SelectedImageInfo = imfinfo(app.SelectedImagePath);
            % Check if the loaded image contains GPS location data
            if ~isfield(SelectedImageInfo, 'GPSInfo')
                msgbox('The loaded image does not contain GPS location data.', 'Information', 'warn');
                return;
            end
        
            % Extract GPS Coordinates:
            gpsData = SelectedImageInfo.GPSInfo;
            latitude = gpsData.GPSLatitude;
            longitude = gpsData.GPSLongitude;
        
            % Convert GPS Coordinates:
            latDegrees = latitude(1) + latitude(2)/60 + latitude(3)/3600;
            lonDegrees = longitude(1) + longitude(2)/60 + longitude(3)/3600;
        
            % Generate Google Maps URL:
            googleMapsURL = sprintf("https://www.google.com/maps/place/%f,%f", latDegrees, lonDegrees);
        
            % Display the Google Maps URL and allow the user to copy or open in a browser
            userChoice = questdlg('Google Maps URL:', 'Location Information', 'Copy URL', 'Open in Browser', 'Copy URL');
            
            switch userChoice
                case 'Copy URL'
                    % Copy the URL to the clipboard
                    clipboard('copy', googleMapsURL);
                    msgbox('Google Maps URL copied to clipboard.', 'Information', 'help');
                case 'Open in Browser'
                    % Open the URL in the default web browser
                    web(googleMapsURL);
                otherwise
                    % User canceled or closed the dialog
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DefectDetectorUIFigure = uifigure('Visible', 'off');
            app.DefectDetectorUIFigure.Name = 'Defect Detector';
            app.DefectDetectorUIFigure.Icon = fullfile(pathToMLAPP, 'gui_v4_icon_1.png');
            app.GridLayout = uigridlayout(app.DefectDetectorUIFigure);
            app.GridLayout.Tooltip = {''};
            app.TrafficSignDefectDetectionLabel = uilabel(app.GridLayout);
            app.TrafficSignDefectDetectionLabel.Text = 'Traffic Sign Defect Detection';
            app.ChooseNeuralNetworksPanel = uipanel(app.GridLayout);
            app.ChooseNeuralNetworksPanel.Title = 'Choose Neural Networks';
            app.GridLayout2 = uigridlayout(app.ChooseNeuralNetworksPanel);
            app.LoadClassifierButton = uibutton(app.GridLayout2, 'push');
            app.LoadClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClassifierButtonPushed, true);
            app.LoadClassifierButton.Text = 'Load Classifier';
            app.LoadDetectorButton = uibutton(app.GridLayout2, 'push');
            app.LoadDetectorButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDetectorButtonPushed, true);
            app.LoadDetectorButton.Text = 'Load Detector';
            app.TrafficSignPanel = uipanel(app.GridLayout);
            app.TrafficSignPanel.Title = 'Traffic Sign';
            app.GridLayout4 = uigridlayout(app.TrafficSignPanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportImagePanel = uipanel(app.GridLayout);
            app.ImportImagePanel.Title = 'Import Image';
            app.GridLayout3 = uigridlayout(app.ImportImagePanel);
            app.BrowseButton = uibutton(app.GridLayout3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Button = uibutton(app.GridLayout, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'dark_icon.png');
            app.Button.Text = '';
            app.LocationButton = uibutton(app.GridLayout, 'push');
            app.LocationButton.ButtonPushedFcn = createCallbackFcn(app, @LocationButtonPushed, true);
            app.LocationButton.Text = 'Location';
            app.DetectButton = uibutton(app.GridLayout, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.ClassifyButton = uibutton(app.GridLayout, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            % Show the figure after all components are created
            app.DefectDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DefectDetectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DefectDetectorUIFigure)
        end
    end
end